classdef MAE547MainPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        JointPositions                matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Tab2                          matlab.ui.container.Tab
        InitializeRobotButton         matlab.ui.control.Button
        InitializeDHParametersButton  matlab.ui.control.Button
        OffsetsCheckBox               matlab.ui.control.CheckBox
        DHSizeEditField               matlab.ui.control.NumericEditField
        DHSizeEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        DHParamApp % Reference to DHParam Window :D
        NumericDHTable % DH Table with NAN
        Robot % the robot duh (rigidbodytree) 
        SymbolicDHTable % DHTable with q1 d1 XD
    end
    
    methods (Access = public)
        
        function setDHParams(app,DHTable)
            app.NumericDHTable = DHTable;
            app.InitializeRobotButton.Enable = "on";
            createSymbolicDH(app)

        end
    end
    
    methods (Access = private)
        
        function createSymbolicDH(app)
            app.SymbolicDHTable = sym(app.NumericDHTable);
            lookup = ['d' 'q'];
            lookupcount = [0 0];
            disp(app.NumericDHTable)
            for i = 1:2
                for j = 1:size(app.NumericDHTable,1)
                    if(isnan(app.NumericDHTable(j,i+2)))
                        lookupcount(i) = lookupcount(i) + 1;
                        app.SymbolicDHTable(j,i+2) = sym(lookup(i) + string(lookupcount(i)));
                    end
                end
            end
            disp(app.SymbolicDHTable)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeDHParametersButton
        function InitializeDHParametersButtonPushed(app, event)
            DHSize = app.DHSizeEditField.Value;
            app.DHParamApp = MAE547DHParamsNoOffset(app,DHSize);
            app.InitializeDHParametersButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DHParamApp)
            delete(app)
        end

        % Button pushed function: InitializeRobotButton
        function InitializeRobotButtonPushed(app, event)
            DHTable = app.NumericDHTable;   
            app.Robot = rigidBodyTree;
            bodies = cell(size(DHTable,1),1);
            joints = cell(size(DHTable,1),1);
            for i = 1:size(DHTable,1)
                DHRow = DHTable(i,:);
                if(isnan(DHRow(4)))
                    %Revolute
                    DHRow(4) = 0;
                    bodies{i} = rigidBody(['body' num2str(i)]);
                    joints{i} = rigidBodyJoint(['jnt' num2str(i)],"revolute");
                    setFixedTransform(joints{i},DHRow,"dh");
                    bodies{i}.Joint = joints{i};
                    if i == 1 % Add first body to base
                        addBody(app.Robot,bodies{i},"base")
                    else % Add current body to previous body by name
                        addBody(app.Robot,bodies{i},bodies{i-1}.Name)
                    end
                elseif(isnan(DHRow(3)))
                    %Prismatic
                    DHRow(3) = 0;
                    bodies{i} = rigidBody(['body' num2str(i)]);
                    joints{i} = rigidBodyJoint(['jnt' num2str(i)],"prismatic");
                    setFixedTransform(joints{i},DHRow,"dh");
                    bodies{i}.Joint = joints{i};
                    if i == 1 % Add first body to base
                        addBody(app.Robot,bodies{i},"base")
                    else % Add current body to previous body by name
                        addBody(app.Robot,bodies{i},bodies{i-1}.Name)
                    end 
                else
                    %Fixed
                    bodies{i} = rigidBody(['body' num2str(i)]);
                    joints{i} = rigidBodyJoint(['jnt' num2str(i)],"fixed");
                    setFixedTransform(joints{i},DHRow,"dh");
                    bodies{i}.Joint = joints{i};
                    if i == 1 % Add first body to base
                        addBody(app.Robot,bodies{i},"base")
                    else % Add current body to previous body by name
                        addBody(app.Robot,bodies{i},bodies{i-1}.Name)
                    end 
                end

            end
            showdetails(app.Robot)
            figure;
            show(app.Robot)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DHSizeEditFieldLabel = uilabel(app.UIFigure);
            app.DHSizeEditFieldLabel.Text = 'DH Size';
            app.DHSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetsCheckBox = uicheckbox(app.UIFigure);
            app.OffsetsCheckBox.Text = 'Offsets';
            app.InitializeDHParametersButton = uibutton(app.UIFigure, 'push');
            app.InitializeDHParametersButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDHParametersButtonPushed, true);
            app.InitializeDHParametersButton.Text = 'Initialize DH Parameters';
            app.InitializeRobotButton = uibutton(app.UIFigure, 'push');
            app.InitializeRobotButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeRobotButtonPushed, true);
            app.InitializeRobotButton.Enable = 'off';
            app.InitializeRobotButton.Text = 'Initialize Robot';
            app.JointPositions = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.JointPositions);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.JointPositions);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAE547MainPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end